classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ConexinROSButton               matlab.ui.control.StateButton
        Home1Button                    matlab.ui.control.Button
        Home2Button                    matlab.ui.control.Button
        Secuencia1Button               matlab.ui.control.Button
        Secuencia2Button               matlab.ui.control.Button
        ImportarconfigPhantomButton    matlab.ui.control.Button
        PickPlacePhantomXPincherLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        l;
        home1;
        home2;
        phantom;
        pub1;
        data1;
        pub2;
        data2; 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear;
            clc;
        end

        % Value changed function: ConexinROSButton
        function ConexinROSButtonValueChanged(app, event)
            value = app.ConexinROSButton.Value;
            if value==1
                [app.pub1, app.data1, app.pub2, app.data2] = rosInit();
            else
                rosshutdown;
            end 
        end

        % Button pushed function: Home1Button
        function Home1ButtonPushed(app, event)
            homePh(app.pub1, app.data1, app.home1);
        end

        % Button pushed function: Home2Button
        function Home2ButtonPushed(app, event)
            homePh(app.pub2, app.data2, app.home2);
        end

        % Button pushed function: Secuencia1Button
        function Secuencia1ButtonPushed(app, event)
            seq1(app.pub1, app.data1, app.l, app.phantom);
        end

        % Button pushed function: Secuencia2Button
        function Secuencia2ButtonPushed(app, event)
            u = 0;
            seq2(app.pub2, app.data2, app.l, app.phantom, u);
        end

        % Button pushed function: ImportarconfigPhantomButton
        function ImportarconfigPhantomButtonPushed(app, event)
            [app.l, app.home1, app.home2, app.phantom] = defPh;
        end

        % Callback function
        function STOPButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConexinROSButton = uibutton(app.UIFigure, 'state');
            app.ConexinROSButton.ValueChangedFcn = createCallbackFcn(app, @ConexinROSButtonValueChanged, true);
            app.ConexinROSButton.Text = {'Conexión'; 'ROS'};
            app.Home1Button = uibutton(app.UIFigure, 'push');
            app.Home1Button.ButtonPushedFcn = createCallbackFcn(app, @Home1ButtonPushed, true);
            app.Home1Button.Text = 'Home 1';
            app.Home2Button = uibutton(app.UIFigure, 'push');
            app.Home2Button.ButtonPushedFcn = createCallbackFcn(app, @Home2ButtonPushed, true);
            app.Home2Button.Text = 'Home 2';
            app.Secuencia1Button = uibutton(app.UIFigure, 'push');
            app.Secuencia1Button.ButtonPushedFcn = createCallbackFcn(app, @Secuencia1ButtonPushed, true);
            app.Secuencia1Button.Text = 'Secuencia 1';
            app.Secuencia2Button = uibutton(app.UIFigure, 'push');
            app.Secuencia2Button.ButtonPushedFcn = createCallbackFcn(app, @Secuencia2ButtonPushed, true);
            app.Secuencia2Button.Text = 'Secuencia 2';
            app.ImportarconfigPhantomButton = uibutton(app.UIFigure, 'push');
            app.ImportarconfigPhantomButton.ButtonPushedFcn = createCallbackFcn(app, @ImportarconfigPhantomButtonPushed, true);
            app.ImportarconfigPhantomButton.Text = {'Importar'; 'config.'; 'Phantom'};
            app.PickPlacePhantomXPincherLabel = uilabel(app.UIFigure);
            app.PickPlacePhantomXPincherLabel.Text = 'Pick & Place PhantomX Pincher';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end